classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIforDynamicStateEstimationProject2UIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        BrowsedataButton              matlab.ui.control.Button
        ShowinputButton               matlab.ui.control.Button
        ComputeDynamicStateEstimationButton  matlab.ui.control.Button
        SelectstatevariableDropDownLabel  matlab.ui.control.Label
        SelectstatevariableDropDown   matlab.ui.control.DropDown
        DisplayselectedstatevariableButton  matlab.ui.control.Button
        ReadfeedbackTextArea          matlab.ui.control.TextArea
        CristianGmezPecesEditFieldLabel  matlab.ui.control.Label
        CristianGmezPecesEditField    matlab.ui.control.EditField
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        FaultAssessmentTextAreaLabel  matlab.ui.control.Label
        FaultAssessmentTextArea       matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: BrowsedataButton
        function BrowsedataButtonPushed(app, event)

            % Read configuration file
            [filename, path] = uigetfile('*.cfg', 'Select CFG File');
            cfg_name = fullfile(path, filename);
            cfg_id = fopen(cfg_name);
            cfg = textscan(cfg_id, '%s', 'delimiter', '\n');
            config_data = textscan(cfg{1,1}{3,1}, '%s', 'Delimiter', ',');
            sample_data = textscan(cfg{1,1}{6,1}, '%s', 'Delimiter', ',');

            v_base = str2double(config_data{1,1}{6,1});
            v_offset = str2double(config_data{1,1}{7,1});
            sampling_rate = str2double(sample_data{1,1}{1,1}); 
            number_samples = str2num(sample_data{1,1}{2,1});
            frequency = str2double(cfg{1,1}{4,1});
            
            % Display text as feedback
            message = {sprintf('Loading configuration file... OK');sprintf('Loading voltage data...');};   
            app.ReadfeedbackTextArea.Value = message;
            
            % Read values file
            [filename, path] = uigetfile('*.dat', 'Select Data file');
            if filename == 0
                disp('Selection cancelled')
            end
            fullname = fullfile(path, filename);
            t_data = readtable(fullname);
            
            save('data/saved_data.mat','t_data','v_base','frequency','v_offset','sampling_rate');
            
            % Display text as feedback
            message = {sprintf('Loading configuration file... OK'); sprintf('Loading voltage data... OK');sprintf('Total samples: %d',number_samples); sprintf('Frequency: %d Hz',frequency); sprintf('Sampling rate: %d',sampling_rate); sprintf('Ready to display input!')};   
            app.ReadfeedbackTextArea.Value = message;


        end

        % Button pushed function: ShowinputButton
        function ShowinputButtonPushed(app, event)
            load('data/saved_data.mat')
            
            voltage = t_data{:,3}*v_base;
            time = t_data{:,2}/1e6;
            app.UIAxes.YLim = [min(voltage*1.1) max(voltage*1.1)];
            app.UIAxes.XLim = [min(time*1.05) max(time)*1.05];
            plot(app.UIAxes,time,voltage)
            
        end

        % Button pushed function: ComputeDynamicStateEstimationButton
        function ComputeDynamicStateEstimationButtonPushed(app, event)
            run('/Users/peces/OneDrive - Georgia Institute of Technology/Winter2020/Protections/Project/project_psp.m')
            
            % Display confidence
            load('data/computed_estimation.mat','p','time')
            plot(app.UIAxes3, time, p')
            
            % Fault assessment
            l = size(p);
            CONFIDENCE_LEVEL = 0.95;
            fault_detected = 0;
            
            for i=2:l(2)-1
                if p(i)<CONFIDENCE_LEVEL
                    fault_detected = i;
                    break
                end
            end
            
            if fault_detected>0
                message = {sprintf('RELAY ACTION REQUIRED');sprintf('Low confidence level detected at t = %.3f seconds',time(fault_detected))};   
            else
                message = {sprintf('No relay action required'); sprintf('Acceptable confidence level for all the data analyzed')};
            end
            app.FaultAssessmentTextArea.Value = message;
            
        end

        % Button pushed function: DisplayselectedstatevariableButton
        function DisplayselectedstatevariableButtonPushed(app, event)
            option = app.SelectstatevariableDropDown.Value;
            load('data/computed_estimation.mat','X','time')
            strings = {'v1 ', 'v2', 'v3 ', 'v4', 'e ', 'lambda ', 'y1 ', 'y2 ', 'y3 ', 'y4 ', 'ip ', 'im ', 'iL1 ', 'iL2 ', 'iL3'};
            red_flag = 0;
            switch option
                case strings{1,1}
                    num_option = 1;
                    label = 'V1(t) (V)';
                case strings{1,2}
                    num_option = 2;
                    label = 'V2(t) (V)';
                case strings{1,3}
                    num_option = 3;
                    label = 'V3(t) (V)';
                case strings{1,4}
                    num_option = 4;
                    label = 'V4(t) (V)';
                case strings{1,5}
                    num_option = 5;
                    label = 'e(t) (V)';
                case strings{1,6}
                    num_option = 6;
                    label = 'Flux leakage (Wb)';
                case strings{1,7}
                    num_option = 7;
                    label = 'Virtual y1';
                case strings{1,8}
                    num_option = 8;
                    label = 'Virtual y2';
                case strings{1,9}
                    num_option = 9;
                    label = 'Virtual y3';
                case strings{1,10}
                    num_option = 10;
                    label = 'Virtual y4';
                case strings{1,11}
                    num_option = 11;
                    label = 'Primary Current (A)';
                case strings{1,12}
                    num_option = 12;
                    label = 'Magnetic Current (A)';
                case strings{1,13}
                    num_option = 13;
                    label = 'i_L1(t) (A)';
                case strings{1,14}
                    num_option = 14;
                    label = 'i_L2(t) (A)';
                case strings{1,15}
                    num_option = 15;
                    label = 'i_L3(t) (A)';
                otherwise
                    disp('No option selected')
                    red_flag = 1;
            end
            if ~red_flag
                app.UIAxes2.YLim = [min(X(num_option,:)*1.1) max(X(num_option,:)*1.1)];
                app.UIAxes2.XLim = [min(time*1.05) max(time)*1.05];
                ylabel(app.UIAxes2, label)
                plot(app.UIAxes2, time, X(num_option,:)')
            end
                 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIforDynamicStateEstimationProject2UIFigure = uifigure;
            app.GUIforDynamicStateEstimationProject2UIFigure.Name = 'GUI for Dynamic State Estimation - Project 2';
            app.UIAxes = uiaxes(app.GUIforDynamicStateEstimationProject2UIFigure);
            title(app.UIAxes, 'Input Data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Measured Output Voltage (V)')
            app.UIAxes.ZTick = [];
            app.BrowsedataButton = uibutton(app.GUIforDynamicStateEstimationProject2UIFigure, 'push');
            app.BrowsedataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsedataButtonPushed, true);
            app.BrowsedataButton.Text = 'Browse data';
            app.ShowinputButton = uibutton(app.GUIforDynamicStateEstimationProject2UIFigure, 'push');
            app.ShowinputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinputButtonPushed, true);
            app.ShowinputButton.Text = 'Show input';
            app.ComputeDynamicStateEstimationButton = uibutton(app.GUIforDynamicStateEstimationProject2UIFigure, 'push');
            app.ComputeDynamicStateEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDynamicStateEstimationButtonPushed, true);
            app.ComputeDynamicStateEstimationButton.Text = 'Compute Dynamic State Estimation';
            app.SelectstatevariableDropDownLabel = uilabel(app.GUIforDynamicStateEstimationProject2UIFigure);
            app.SelectstatevariableDropDownLabel.Text = 'Select state variable  ';
            app.SelectstatevariableDropDown = uidropdown(app.GUIforDynamicStateEstimationProject2UIFigure);
            app.SelectstatevariableDropDown.Items = {'Select...', 'v1 ', 'v2', 'v3 ', 'v4', 'e ', 'lambda ', 'y1 ', 'y2 ', 'y3 ', 'y4 ', 'ip ', 'im ', 'iL1 ', 'iL2 ', 'iL3'};
            app.SelectstatevariableDropDown.Value = 'Select...';
            app.DisplayselectedstatevariableButton = uibutton(app.GUIforDynamicStateEstimationProject2UIFigure, 'push');
            app.DisplayselectedstatevariableButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayselectedstatevariableButtonPushed, true);
            app.DisplayselectedstatevariableButton.Text = 'Display selected state variable';
            app.ReadfeedbackTextArea = uitextarea(app.GUIforDynamicStateEstimationProject2UIFigure);
            app.ReadfeedbackTextArea.Value = {'Press ''Browse'' to load data'; '1) Select .CFG file'; '2) Select .DAT file'; ''; 'This program assumes COMTRADE format'; ''};
            app.CristianGmezPecesEditFieldLabel = uilabel(app.GUIforDynamicStateEstimationProject2UIFigure);
            app.CristianGmezPecesEditFieldLabel.Text = '© 2020 Cristian Gómez Peces';
            app.CristianGmezPecesEditField = uieditfield(app.GUIforDynamicStateEstimationProject2UIFigure, 'text');
            app.CristianGmezPecesEditField.Visible = 'off';
            app.UIAxes2 = uiaxes(app.GUIforDynamicStateEstimationProject2UIFigure);
            title(app.UIAxes2, 'State Variable Estimation')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Value')
            app.UIAxes3 = uiaxes(app.GUIforDynamicStateEstimationProject2UIFigure);
            title(app.UIAxes3, 'Confidence Level of Measurements')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Confidence Level')
            app.UIAxes3.YLim = [0 1.1];
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1 1.2];
            app.UIAxes3.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'};
            app.UIAxes3.YTick = [0 0.2 0.4 0.6 0.8 1 1.2];
            app.UIAxes3.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'};
            app.FaultAssessmentTextAreaLabel = uilabel(app.GUIforDynamicStateEstimationProject2UIFigure);
            app.FaultAssessmentTextAreaLabel.Text = 'Fault Assessment';
            app.FaultAssessmentTextArea = uitextarea(app.GUIforDynamicStateEstimationProject2UIFigure);
            app.FaultAssessmentTextArea.Value = {'Waiting for estimation...'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIforDynamicStateEstimationProject2UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIforDynamicStateEstimationProject2UIFigure)
        end
    end
end